classdef GUI_QSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        RUNSIMULATIONButton             matlab.ui.control.Button
        CurrentlyloadedparametersEditField  matlab.ui.control.EditField
        CurrentlyloadedparametersEditFieldLabel  matlab.ui.control.Label
        CurrentlyloadedmodelEditField   matlab.ui.control.EditField
        CurrentlyloadedmodelEditFieldLabel  matlab.ui.control.Label
        TUeQSSToolboxLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ModelTab                        matlab.ui.container.Tab
        ExamplesPanel                   matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ChooseapowertraintypeDropDown   matlab.ui.control.DropDown
        ChooseapowertraintypeDropDownLabel  matlab.ui.control.Label
        ChooseavehicletypeDropDown      matlab.ui.control.DropDown
        ChooseavehicletypeDropDownLabel  matlab.ui.control.Label
        LoadmodelButton_3               matlab.ui.control.Button
        LoadandopenmodelButton_3        matlab.ui.control.Button
        OpenexistingmodelPanel          matlab.ui.container.Panel
        LoadmodelButton_2               matlab.ui.control.Button
        LoadandopenmodelButton_2        matlab.ui.control.Button
        CreatenewmodelPanel             matlab.ui.container.Panel
        CreatemodelButton               matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.Tab
        OpenexistingparameterfilePanel  matlab.ui.container.Panel
        OpenparameterfileButton         matlab.ui.control.Button
        PlotsTab                        matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MotorscalingTab                 matlab.ui.container.Tab
    end

    
    properties (Access = public)
        exampleName
        model
        parameter
        results
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.model.name = app.ChooseavehicletypeDropDown.Value;
            app.parameter.name = '---';
            app.CurrentlyloadedmodelEditField.Value = '---';
            app.CurrentlyloadedparametersEditField.Value = '---';
        end

        % Value changed function: ChooseavehicletypeDropDown
        function ChooseavehicletypeDropDownValueChanged(app, event)
            app.model.veh_type = app.ChooseavehicletypeDropDown.Value;           % read model example name form dropdown menu
        end

        % Value changed function: ChooseapowertraintypeDropDown
        function ChooseapowertraintypeDropDownValueChanged(app, event)
            app.model.name = app.ChooseapowertraintypeDropDown.Value;
        end

        % Button pushed function: LoadandopenmodelButton_3
        function LoadandopenmodelButtonPushed(app, event)
            open_system(app.model.name);                                  % load and open the example model
            app.CurrentlyloadedmodelEditField.Value = app.model.name;     % update the display
        end

        % Button pushed function: LoadandopenmodelButton_2
        function LoadandopenmodelButton_2Pushed(app, event)
            [app.model.name,app.model.path] = uigetfile('*.slx');         % ask user to choose .slx file
            addpath(app.model.path);                                      % add the file path to the Matlab path
            open_system(app.model.name);                                  % load and open the file
            app.CurrentlyloadedmodelEditField.Value = app.model.name;     % update the display
        end

        % Button pushed function: OpenparameterfileButton
        function OpenparameterfileButtonPushed(app, event)
            [app.parameter.name,app.parameter.path] = uigetfile('*.mat');        % ask user to choose .mat file
            addpath(app.parameter.path);                                         % add the file path to the Matlab path
            load(app.parameter.name);                                            % load the file
            app.CurrentlyloadedparametersEditField.Value = app.parameter.name;   % update the display
        end

        % Button pushed function: LoadmodelButton_3
        function LoadmodelButtonPushed(app, event)
            load_system(app.model.name);                                  % load the example model
            app.CurrentlyloadedmodelEditField.Value = app.model.name;     % update the display
        end

        % Button pushed function: LoadmodelButton_2
        function LoadmodelButton_2Pushed(app, event)
            [app.model.name,app.model.path] = uigetfile('*.slx');         % ask user to choose .slx file
            addpath(app.model.path);                                      % add the file path to the Matlab path
            load_system(app.model.name);                                  % load the file
            app.CurrentlyloadedmodelEditField.Value = app.model.name;     % update display
        end

        % Button pushed function: RUNSIMULATIONButton
        function RUNSIMULATIONButtonPushed(app, event)
            if strcmp(app.model.name, 'BEV') || strcmp(app.model.name, 'HEV') || strcmp(app.model.name, 'ICEV')
                app.results = RunExamples(app.model.veh_type, app.model.name);
            else
                sim(app.model.name);
            end
        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            simulink
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.CreatenewmodelPanel = uipanel(app.ModelTab);
            app.CreatenewmodelPanel.Title = 'Create new model';
            app.CreatemodelButton = uibutton(app.CreatenewmodelPanel, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Text = 'Create model';
            app.OpenexistingmodelPanel = uipanel(app.ModelTab);
            app.OpenexistingmodelPanel.Title = 'Open existing model';
            app.LoadandopenmodelButton_2 = uibutton(app.OpenexistingmodelPanel, 'push');
            app.LoadandopenmodelButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadandopenmodelButton_2Pushed, true);
            app.LoadandopenmodelButton_2.Text = 'Load and open model';
            app.LoadmodelButton_2 = uibutton(app.OpenexistingmodelPanel, 'push');
            app.LoadmodelButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelButton_2Pushed, true);
            app.LoadmodelButton_2.Text = 'Load model';
            app.ExamplesPanel = uipanel(app.ModelTab);
            app.ExamplesPanel.Title = 'Examples';
            app.GridLayout2 = uigridlayout(app.ExamplesPanel);
            app.LoadandopenmodelButton_3 = uibutton(app.GridLayout2, 'push');
            app.LoadandopenmodelButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadandopenmodelButtonPushed, true);
            app.LoadandopenmodelButton_3.Text = 'Load and open model';
            app.LoadmodelButton_3 = uibutton(app.GridLayout2, 'push');
            app.LoadmodelButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelButtonPushed, true);
            app.LoadmodelButton_3.Text = 'Load model';
            app.ChooseavehicletypeDropDownLabel = uilabel(app.GridLayout2);
            app.ChooseavehicletypeDropDownLabel.Text = 'Choose a vehicle type';
            app.ChooseavehicletypeDropDown = uidropdown(app.GridLayout2);
            app.ChooseavehicletypeDropDown.Items = {'Compact', 'Midsize', 'SUV'};
            app.ChooseavehicletypeDropDown.ItemsData = {'BEV.slx', 'HEV.slx', 'ICEV.slx'};
            app.ChooseavehicletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseavehicletypeDropDownValueChanged, true);
            app.ChooseavehicletypeDropDown.Value = 'BEV.slx';
            app.ChooseapowertraintypeDropDownLabel = uilabel(app.GridLayout2);
            app.ChooseapowertraintypeDropDownLabel.Text = 'Choose a powertrain type';
            app.ChooseapowertraintypeDropDown = uidropdown(app.GridLayout2);
            app.ChooseapowertraintypeDropDown.Items = {'Single-speed BEV', '5-speed HEV', '5-speed ICEV'};
            app.ChooseapowertraintypeDropDown.ItemsData = {'BEV', 'HEV', 'ICEV'};
            app.ChooseapowertraintypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseapowertraintypeDropDownValueChanged, true);
            app.ChooseapowertraintypeDropDown.Value = 'BEV';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.OpenexistingparameterfilePanel = uipanel(app.ParametersTab);
            app.OpenexistingparameterfilePanel.Title = 'Open existing parameter file';
            app.OpenparameterfileButton = uibutton(app.OpenexistingparameterfilePanel, 'push');
            app.OpenparameterfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenparameterfileButtonPushed, true);
            app.OpenparameterfileButton.Text = 'Open parameter file';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.UIAxes = uiaxes(app.PlotsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.PlotsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.PlotsTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.MotorscalingTab = uitab(app.TabGroup);
            app.MotorscalingTab.Title = 'Motor scaling';
            app.TUeQSSToolboxLabel = uilabel(app.GridLayout);
            app.TUeQSSToolboxLabel.Text = 'TU/e QSS Toolbox ';
            app.CurrentlyloadedmodelEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentlyloadedmodelEditFieldLabel.Text = 'Currently loaded model';
            app.CurrentlyloadedmodelEditField = uieditfield(app.GridLayout, 'text');
            app.CurrentlyloadedmodelEditField.Editable = 'off';
            app.CurrentlyloadedparametersEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentlyloadedparametersEditFieldLabel.Text = 'Currently loaded parameters';
            app.CurrentlyloadedparametersEditField = uieditfield(app.GridLayout, 'text');
            app.CurrentlyloadedparametersEditField.Editable = 'off';
            app.RUNSIMULATIONButton = uibutton(app.GridLayout, 'push');
            app.RUNSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.RUNSIMULATIONButton.Text = 'RUN SIMULATION';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'tue_logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_QSS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end